classdef workshopApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WorkshopUI             matlab.ui.Figure
        ActuatorStatusPanel    matlab.ui.container.Panel
        EnableButton           matlab.ui.control.StateButton
        StateTextArea          matlab.ui.control.TextArea
        ErrorLamp              matlab.ui.control.Lamp
        ErrorLampLabel         matlab.ui.control.Label
        WarningLamp            matlab.ui.control.Lamp
        WarningLampLabel       matlab.ui.control.Label
        HomedLamp              matlab.ui.control.Lamp
        HomedLampLabel         matlab.ui.control.Label
        OpEnabledLamp          matlab.ui.control.Lamp
        OpEnabledLampLabel     matlab.ui.control.Label
        AppstatusPanel         matlab.ui.container.Panel
        eCatStateEditField     matlab.ui.control.EditField
        eCatEditFieldLabel     matlab.ui.control.Label
        TimeEditField          matlab.ui.control.NumericEditField
        TimeEditFieldLabel     matlab.ui.control.Label
        StartStopTargetButton  matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        startIcon = fullfile('Icons','Run_24.png')
        stopIcon = fullfile('Icons','stop_24.png')

        mdlName
        mdlNameTs
        tgName
        decimation
        decimationAxes
        decimationReduced
        slBuildDir
        
        hInstScalar    
        hInstLine      
        lastClearTime
        tg
        axesDt

        ledOff = [0.94,0.94,0.94];
        ledGreenOn = [0.09, 0.77, 0];
        ledRedOn = [0.65, 0.06, 0.06];
        ledAmberOn = [0.81,0.37,0.01];

    end
    
    methods (Access = private)
        
        function setupInstrumentation(app)
            %disp('In instrumentation')

            % creat an instrumentation object 
            app.hInstScalar     = slrealtime.Instrument;
            %app.hInstLine       = slrealtime.Instrument;

            % time and general states
            app.hInstScalar.connectScalar(app.eCatStateEditField,[app.mdlName,'/eCatStatus/UI/eCatState'],1,'Decimation',app.decimationReduced,'Callback',@(t,d)app.eCATStateStr(d));
            app.hInstScalar.connectScalar(app.StateTextArea,[app.mdlName,'/fileAndUI/ctrlSignalUI/state'],1,'Decimation',app.decimationReduced,'Callback',@(t,d)app.actStateStr(d));
            app.hInstScalar.connectScalar(app.TimeEditField,[app.mdlName,'/diagnostics/time_s'],1,'Decimation',app.decimation);
            
            % linmot status
            app.hInstScalar.connectScalar(app.OpEnabledLamp,[app.mdlName,'/fileAndUI/actStatusUI/opEnabled'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2GreyGreenLED(d)); 
            app.hInstScalar.connectScalar(app.HomedLamp,[app.mdlName,'/fileAndUI/actStatusUI/homed'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2AmberGreenLED(d)); 
            app.hInstScalar.connectScalar(app.WarningLamp,[app.mdlName,'/fileAndUI/actStatusUI/warning'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2GreyAmberLED(d)); 
            app.hInstScalar.connectScalar(app.ErrorLamp,[app.mdlName,'/fileAndUI/actStatusUI/error'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2GreyRedLED(d)); 
 
            % a general callback to get tg properties
            %connectCallback(app.hInstLine,@app.regularCallback);

            % add the instrument object to the app - LAST call
            app.tg.addInstrument(app.hInstScalar);
            %app.tg.addInstrument(app.hInstLine);

            % legends after adding the instruments
            %legend(app.someAxis, 'X','Y','Z')
            
        end
    
        function regularCallback(app,~,eventData)

            % update the UI axes
            timeNow = eventData.ExecTime;
            now = max(timeNow); % could be more than one time
            if now >= max(app.imuAnglesUIAxes.XLim)
          
                % clear line data
                app.hInstLine.clearScalarAndLineData();
                app.lastClearTime = max(timeNow);
          
                % adjust the xlim
              %  app.someAxis.XLim = floor(max(timeNow)/app.axesDt)*app.axesDt + [0 app.axesDt];
                
                % set the ylim for all three UI axes
                %app.axesYlim();
            end 
            
        end % regular callback

        % convert EtherCAT state number to string
        function str = eCATStateStr(~,data)
        
            str = 'NAN';
            if sum(size(data) >= 1) % could be more than one
                useData = data(1);
                dataStr = num2str(useData);
                switch useData
                    case uint32(eCATStateEnum.init)
                        str = ['Init (',dataStr,')'];
                    case uint32(eCATStateEnum.preOp)
                        str = ['Pre Op (',dataStr,')'];
                    case uint32(eCATStateEnum.safeOp)
                        str = ['Safe Op (',dataStr,')'];
                    case uint32(eCATStateEnum.op)
                        str = ['Op (',dataStr,')'];
                    otherwise
                        str = ['Not defined (',dataStr,')'];
                end
            end
        end

        function str = actStateStr(~,data)
        
            str = 'NAN';
            if sum(size(data) >= 1) % could be more than one
                useData = data(1);
                dataStr = num2str(useData);
                switch useData
                    case uint32(linmotStateEnum.init)
                        str = ['Init (',dataStr,')'];
                    case uint32(linmotStateEnum.readyToEnable)
                        str = ['Ready to enable (',dataStr,')'];
                    case uint32(linmotStateEnum.switchingOn)
                        str = ['Swiching On (',dataStr,') ...'];
                    case uint32(linmotStateEnum.homing)
                        str = ['Homing (',dataStr,') ...'];
                    case uint32(linmotStateEnum.enabled)
                        str = ['Enabled (',dataStr,'). Ready to start experiment.'];
                    otherwise
                        str = ['Not defined (',dataStr,')'];
                end
            end
        end


        function led = sig2GreyGreenLED(app,data)
            if data < 10*eps % make sure this also works on floats
                led = app.ledOff;
            else
                led = app.ledGreenOn;
            end
        end

        function led = sig2GreyRedLED(app,data)
            if data < 10*eps % make sure this also works on floats
                led = app.ledOff;
            else
                led = app.ledRedOn;
            end
        end

        function led = sig2GreyAmberLED(app,data)
            if data < 10*eps % make sure this also works on floats
                led = app.ledOff;
            else
                led = app.ledAmberOn;
            end
        end

        function led = sig2AmberGreenLED(app,data)
            if data < 10*eps % make sure this also works on floats
                led = app.ledAmberOn;
            else
                led = app.ledGreenOn;
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            d = uiprogressdlg(app.WorkshopUI,'Title','Please Wait','Message','Running the startup function ...');

            %% set up UI axes
            d.Value = 0.1;
            d.Message = 'Setting up UI axes';
        
            %disableDefaultInteractivity(app.imuAnglesUIAxes)
            %app.someAxis.Interruptible  = 'on'; %'off';
            %app.someAxis.BusyAction = 'queue'; %'cancel';

            app.lastClearTime = 0;
          
            %% get model information
            d.Value = 0.2;
            d.Message = 'Loading workspace data';

            app.slBuildDir = evalin('base','slBuildDir');

            %app.axesDt = evalin('base','axesDt');
            app.mdlName = evalin('base','mdlName'); 
            app.mdlNameTs = evalin('base','mdlNameTs'); 
            app.tgName = evalin('base','tgName');
            app.decimation = evalin('base','appDecimation');
            app.decimationAxes = evalin('base','appDecimationAxes');
            app.decimationReduced = evalin('base','appDecimationReduced');
             
            %% connect the RT and instrument objects
            d.Value = 0.2;
            d.Message = ['Connecting to the RT target',app.tgName, ' ...'];

            app.tg = slrealtime(app.tgName);
            app.tg.stop;
            
            %% load the model on to the target 
            d.Value = 0.5;
            d.Message = ['Loading the model ',app.mdlName,' ...'];
            app.tg.load(fullfile(app.slBuildDir,app.mdlNameTs));

            %% remove any instrument pannels from previous sessions
            app.tg.removeAllInstruments()

            app.StartStopTargetButton.Icon = app.startIcon;
            app.StartStopTargetButton.Text = 'Start Speedgoat';
            
            %app.ExpStartStopButton.Icon = app.startIcon;
            %app.ExpStartStopButton.Text = 'Start Experiment';

            %% setup the instrumentation object
            d.Value = 0.6;
            d.Message = 'Setting up instrumentation pannel ...';

            app.setupInstrumentation;

            d.Value = 0.8;
            d.Message = 'Setting up App ...';
            
            %% close progress bar
            d.close();


        end

        % Value changed function: StartStopTargetButton
        function StartStopTargetButtonValueChanged(app, event)
            value = app.StartStopTargetButton.Value;
           
            if value 
            
                d = uiprogressdlg(app.WorkshopUI,'Title','Please Wait','Message','Starting target ...');
            
                % START - no need to load; was aleady loaded with app startup
            
                d.Value = 0.2;
                d.Message = ['Starting the model ',app.mdlNameTs,' ...'];
                app.tg.start('AutoImportFileLog',false,'ExportToBaseWorkspace',false,'FileLogMaxRuns',100,'ReloadOnStop',true); 
            
                % change the icon
                d.Value = 0.5;
                d.Message = 'Updating app ...';
                app.StartStopTargetButton.Icon = app.stopIcon;
                app.StartStopTargetButton.Text = 'Stop Speedgoat';
    
                %app.axesYlim();
 
                d.close();
            
            else
                % STOP
                d = uiprogressdlg(app.WorkshopUI,'Title','Please Wait','Message','Stopping target ...');
               
                d.Value = 0.4;
                d.Message = 'Stopping target ...';
                app.tg.stop;
                
                % change the icon
                d.Value = 0.8;
                d.Message = 'Updating app ...';
                app.StartStopTargetButton.Icon = app.startIcon;
                app.StartStopTargetButton.Text = 'Start Speedgoat';

                d.close()
            end


        end

        % Value changed function: EnableButton
        function EnableButtonValueChanged(app, event)
            value = app.EnableButton.Value;
             if value
                app.EnableButton.Text = 'Disable Actuator';
            else
                app.EnableButton.Text = 'Enable Actuator';
            end
            app.tg.setparam([app.mdlName,'/processCtrl/enable'],'Value',value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WorkshopUI = uifigure('Visible', 'off');
            app.WorkshopUI.Name = 'MATLAB App';
            app.AppstatusPanel = uipanel(app.WorkshopUI);
            app.AppstatusPanel.Title = 'App status';
            app.StartStopTargetButton = uibutton(app.AppstatusPanel, 'state');
            app.StartStopTargetButton.ValueChangedFcn = createCallbackFcn(app, @StartStopTargetButtonValueChanged, true);
            app.StartStopTargetButton.Text = 'Start/Stop Target';
            app.TimeEditFieldLabel = uilabel(app.AppstatusPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.AppstatusPanel, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%4.2f s';
            app.eCatEditFieldLabel = uilabel(app.AppstatusPanel);
            app.eCatEditFieldLabel.Text = 'eCat';
            app.eCatStateEditField = uieditfield(app.AppstatusPanel, 'text');
            app.ActuatorStatusPanel = uipanel(app.WorkshopUI);
            app.ActuatorStatusPanel.Title = 'Actuator Status';
            app.OpEnabledLampLabel = uilabel(app.ActuatorStatusPanel);
            app.OpEnabledLampLabel.Text = 'Op Enabled';
            app.OpEnabledLamp = uilamp(app.ActuatorStatusPanel);
            app.HomedLampLabel = uilabel(app.ActuatorStatusPanel);
            app.HomedLampLabel.Text = 'Homed';
            app.HomedLamp = uilamp(app.ActuatorStatusPanel);
            app.WarningLampLabel = uilabel(app.ActuatorStatusPanel);
            app.WarningLampLabel.Text = 'Warning';
            app.WarningLamp = uilamp(app.ActuatorStatusPanel);
            app.ErrorLampLabel = uilabel(app.ActuatorStatusPanel);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.ActuatorStatusPanel);
            app.StateTextArea = uitextarea(app.ActuatorStatusPanel);
            app.EnableButton = uibutton(app.ActuatorStatusPanel, 'state');
            app.EnableButton.ValueChangedFcn = createCallbackFcn(app, @EnableButtonValueChanged, true);
            app.EnableButton.Text = 'Enable';
            % Show the figure after all components are created
            app.WorkshopUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = workshopApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WorkshopUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WorkshopUI)
        end
    end
end